classdef uhfsae_data_acquisition_system < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        ArduinoConnectionPanel         matlab.ui.container.Panel
        ConnectButton                  matlab.ui.control.Button
        ArduinoDropDownLabel           matlab.ui.control.Label
        ArduinoDropDown                matlab.ui.control.DropDown
        SerialPortEditFieldLabel       matlab.ui.control.Label
        SerialPortEditField            matlab.ui.control.EditField
        ConnectionLamp                 matlab.ui.control.Lamp
        TextArea                       matlab.ui.control.TextArea
        LoadCellGroup                  matlab.ui.container.TabGroup
        FrontLeftTab                   matlab.ui.container.Tab
        DataOutPinEditFieldLabel       matlab.ui.control.Label
        FLDataOutPinEditField          matlab.ui.control.EditField
        ClockSCKPinEditFieldLabel      matlab.ui.control.Label
        FLClockSCKPinEditField         matlab.ui.control.EditField
        TareValueEditFieldLabel        matlab.ui.control.Label
        FLTareValueEditField           matlab.ui.control.NumericEditField
        ScaleFactorEditFieldLabel      matlab.ui.control.Label
        FLScaleFactorEditField         matlab.ui.control.NumericEditField
        MaxLoadgEditFieldLabel         matlab.ui.control.Label
        FLMaxLoadgEditField            matlab.ui.control.NumericEditField
        FLTareButton                   matlab.ui.control.Button
        FLCalibrateButton              matlab.ui.control.Button
        KnownWeightgLabel              matlab.ui.control.Label
        FLKnownWeightgEditField        matlab.ui.control.NumericEditField
        FLDataLamp                     matlab.ui.control.Lamp
        FLReceiveDataButton            matlab.ui.control.Button
        FLStopDataButton               matlab.ui.control.Button
        PauseReadingsButton            matlab.ui.control.Button
        ResumeReadingsButton           matlab.ui.control.Button
        FrontRightTab                  matlab.ui.container.Tab
        BackLeftTab                    matlab.ui.container.Tab
        BackRightTab                   matlab.ui.container.Tab
        CenterPanel                    matlab.ui.container.Panel
        FLLoadCell                     matlab.ui.control.NumericEditField
        RetrieveRawValueFLButton       matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
        FLLabel                        matlab.ui.control.Label
        LoadCellLabel                  matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        FRLoadCell                     matlab.ui.control.NumericEditField
        FRLabel                        matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        TorsionalRigidityCalculationPanel  matlab.ui.container.Panel
        InitialPositionEditFieldLabel  matlab.ui.control.Label
        InitialPosition1EditField      matlab.ui.control.NumericEditField
        DialIndicatorHubInLabel        matlab.ui.control.Label
        DisplacedPositionEditField_2Label  matlab.ui.control.Label
        DisplacedPosition1EditField    matlab.ui.control.NumericEditField
        DialIndicatorNodeinLabel       matlab.ui.control.Label
        InitialPositionEditField_2Label  matlab.ui.control.Label
        InitialPosition2EditField      matlab.ui.control.NumericEditField
        DisplacedPositionEditFieldLabel  matlab.ui.control.Label
        DisplacedPosition2EditField    matlab.ui.control.NumericEditField
        GetTorsionalRigidityButton     matlab.ui.control.Button
        TorsionalRigidityOutput        matlab.ui.control.NumericEditField
        DeformationAngleLabel          matlab.ui.control.Label
        DeformationAngle1EditField     matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        DistanceBetweenDialIndicatorsinEditFieldLabel  matlab.ui.control.Label
        DistanceBetweenDialIndicatorsinEditField  matlab.ui.control.NumericEditField
        DistancebetweentheJackScrewsinEditFieldLabel  matlab.ui.control.Label
        DistancebetweentheJackScrewsinEditField  matlab.ui.control.NumericEditField
        ResetDisplacedValuesButton     matlab.ui.control.Button
        SaveLamp                       matlab.ui.control.Lamp
        SaveAllValuestoExcelSheetsLabel  matlab.ui.control.Label
        SaveTorsionalRigidity          matlab.ui.control.Switch
        LoadCellValuesandTorsionalRigidityValueswithTimeLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        ard % The arduino
        type % The arduino type
        serial % The port that the arduino is using, ex. 'COM3'
        connected % Connection status
        loadCell1 % Load cells 1-4
        loadCell2
        loadCell3
        loadCell4
        %tareValue
        tareReady = 0 % Counter for starting tare function
        tareN = 10 % number of readings for tare value
        calibrateReady = 0 % Counter for starting calibration function
        calN = 10 % number of readings for calibration value
        scalar = 0 % value to scale readings to max weight of load cell
        knownWeight % a weight in grams used to calibrate the load cell
        FLdataReady = 0 % a number that allows the data reading of the FL load cell to be toggled
        activeLoadCells % count of load cells displaying data
        pauseValue = 0 % a way to pause and unpause data collection/plotting
        torsionalRigidity = 0 %the torsional rigidity, equation is below
        % (Ct=(Load Cell Force (FL Hub) * (½ * distance between "Jack Screws"))/
        % (arctan[(displacement dial indicator (FL Hub) + displacement dial indicator (FR Hub))/Distance between dial indicators])
        jackScrewDistance % distance between jack screws
        dialIndDistance % distance between dial indicators
        deformationAngle % angle of deformation between dial indicators
        torque % torque
        torsionalRigidityTable %table of values in the displayed table
        xVals % time values from graph for export
        yVals1 % load cell 1 (FL) values from graph for export
    end
    
    properties (Access = public)
        tareValue = 0
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'UH FSAE Torsional Rigidity Calculator';
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            app.TextArea.Value = 'Please Wait for Connection Confirmation.';
            app.serial = app.SerialPortEditField.Value;
            app.type=app.ArduinoDropDown.Value;
            app.ard = arduino(app.serial,app.type,'libraries',{'basicHX711/basic_HX711'});
            app.loadCell1 = addon(app.ard,'basicHX711/basic_HX711',{app.FLDataOutPinEditField.Value,app.FLClockSCKPinEditField.Value}); 
            % Pins D4 and D5 are data (Serial Data Output[DOUT]) then clock (Serial Clock Input[SCK])
            % assign load cells 2-4 here, already instantiated in private variables
            app.TextArea.Value = 'Connected.';
            app.ConnectionLamp.Color = [0 1 0 ];
            app.activeLoadCells = 0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 229};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Button pushed function: FLTareButton
        function FLTareButtonPushed(app, event)
            if app.tareReady < 1
                app.TextArea.Value = 'Please confirm weight is off loadcell for taring, then click tare again.';
                app.tareReady = app.tareReady+1;
            else
                app.TextArea.Value = 'Calculating tare value. Please wait.';
                x=zeros(app.tareN,1);
                for i = 1:app.tareN
                    x(i) = read_HX711(app.loadCell1);
                    pause(1/100);
                end
                app.tareValue = sum(x)/app.tareN;
                app.FLTareValueEditField.Value = app.tareValue;
                app.TextArea.Value = 'Tare value is calculated and displayed in the tare field.';
            end
        end

        % Button pushed function: FLCalibrateButton
        function FLCalibrateButtonPushed(app, event)
            if app.calibrateReady < 1
                app.TextArea.Value = 'Please place a known weight on the load cell and enter its weight in grams.';
                app.calibrateReady = app.calibrateReady+1;
            else
                app.TextArea.Value = 'Beginning calibration.';
                y=zeros(app.calN,1);
                for j = 1:app.calN
                   y(j) = read_HX711(app.loadCell1);
                   pause(1/100);
                end
                if app.FLKnownWeightgEditField.Value > 0
                    app.scalar = ((sum(y)/app.calN)-app.tareValue)/app.FLKnownWeightgEditField.Value;
                    app.FLScaleFactorEditField.Value = app.scalar;
                    app.TextArea.Value = 'The load cell is calibrated.';
                else
                    app.TextArea.Value = 'You need to input a known weight for the load cell.';
                end
            end
        end

        % Button pushed function: RetrieveRawValueFLButton
        function RetrieveRawValueFLButtonPushed(app, event)
            if app.tareValue == 0
                app.tareValue = app.FLTareValueEditField.Value;
            end
            if app.scalar == 0
                app.scalar = app.FLScaleFactorEditField.Value;
            end
            app.FLLoadCell.Value=(read_HX711(app.loadCell1)-app.tareValue)/app.scalar;
        end

        % Button pushed function: FLReceiveDataButton
        function FLReceiveDataButtonPushed(app, event)
                app.FLdataReady=1;
                app.pauseValue=1;
            if app.tareValue == 0
                app.tareValue = app.FLTareValueEditField.Value;
            end
            if app.scalar == 0
                app.scalar = app.FLScaleFactorEditField.Value;
            end
            y=[];
            x=[];
            tic();
            while mod(app.FLdataReady,2) 
                if mod(app.pauseValue,2)
                    app.activeLoadCells = app.activeLoadCells + 1;
                    app.FLDataLamp.Color = [0 1 0];
                    y=[y,(read_HX711(app.loadCell1)-app.tareValue)/app.scalar];
                    x=[x,toc()];
                    app.FLLoadCell.Value=(read_HX711(app.loadCell1)-app.tareValue)/app.scalar;
                    plot(app.UIAxes,x,y);
                    app.deformationAngle = abs(rad2deg(atan((abs(app.InitialPosition2EditField.Value-app.DisplacedPosition2EditField.Value)-abs(app.InitialPosition1EditField.Value-app.DisplacedPosition1EditField.Value))...
                /app.DistanceBetweenDialIndicatorsinEditField.Value)));
                    app.DeformationAngle1EditField.Value = app.deformationAngle;
                    app.xVals=x;
                    app.yVals1=y;
                else
                    pause(1/100);
                end
            end
        end

        % Button pushed function: FLStopDataButton
        function FLStopDataButtonPushed(app, event)
            app.FLdataReady=app.FLdataReady + 1;
            app.activeLoadCells = app.activeLoadCells - 1;
            app.FLDataLamp.Color = [0 0 0];
        end

        % Button pushed function: PauseReadingsButton
        function PauseReadingsButtonPushed(app, event)
            app.pauseValue = app.pauseValue + 1;
            app.FLDataLamp.Color = [0 0 1];
        end

        % Button pushed function: ResumeReadingsButton
        function ResumeReadingsButtonPushed(app, event)
            app.pauseValue = app.pauseValue + 1;
            app.FLDataLamp.Color = [0 1 0];
        end

        % Button pushed function: ResetDisplacedValuesButton
        function ResetDisplacedValuesButtonPushed(app, event)
            app.DisplacedPosition1EditField.Value = app.InitialPosition1EditField.Value;
            app.DisplacedPosition2EditField.Value = app.InitialPosition2EditField.Value;
        end

        % Button pushed function: GetTorsionalRigidityButton
        function GetTorsionalRigidityButtonPushed(app, event)
            %Ct=(Load Cell Force (FL Hub) * (½ * distance between "Jack Screws”))/
            %(arctan[(displacement dial indicator (FL Hub) + displacement dial indicator (FR Hub))/Distance between dial indicators]
            app.jackScrewDistance = app.DistancebetweentheJackScrewsinEditField.Value;
            app.dialIndDistance = app.DistanceBetweenDialIndicatorsinEditField.Value;
            app.torque = abs(app.FLLoadCell.Value * 0.5 * app.jackScrewDistance);
            app.torsionalRigidity = app.torque / app.deformationAngle;
            app.torsionalRigidityTable = [app.torsionalRigidityTable; toc() app.deformationAngle app.torque app.torsionalRigidity];
            app.UITable.Data = [app.torsionalRigidityTable];
            app.UITable.ColumnFormat = {'bank' 'bank' 'bank' 'bank'};
            app.TorsionalRigidityOutput.Value = app.torsionalRigidity;
        end

        % Value changed function: SaveTorsionalRigidity
        function SaveTorsionalRigidityValueChanged(app, event)
            value = app.SaveTorsionalRigidity.Value;
            if value == 'Yes'
                if isfile('Torsional_Rigidity_Snapshot.xlsx')
                    delete 'Torsional_Rigidity_Snapshot.xlsx';
                    delete 'Load_Time_Values.xlsx';
                end
                writecell({'Time','Deformation Angle','Torque','Torsional Rigidity'},'Torsional_Rigidity_Snapshot.xlsx');
                writematrix(app.UITable.Data,'Torsional_Rigidity_Snapshot.xlsx',"Range","A2");
                writecell({'Time','Load'},'Load_Time_Values.xlsx');
                writematrix([app.xVals', app.yVals1'],'Load_Time_Values.xlsx',"Range","A2");
                app.SaveTorsionalRigidity.Value = 'No';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ArduinoConnectionPanel = uipanel(app.LeftPanel);
            app.ArduinoConnectionPanel.Title = 'Arduino Connection';
            app.ConnectButton = uibutton(app.ArduinoConnectionPanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ArduinoDropDownLabel = uilabel(app.ArduinoConnectionPanel);
            app.ArduinoDropDownLabel.Text = 'Arduino:';
            app.ArduinoDropDown = uidropdown(app.ArduinoConnectionPanel);
            app.ArduinoDropDown.Items = {'Uno', 'Mega'};
            app.ArduinoDropDown.Value = 'Uno';
            app.SerialPortEditFieldLabel = uilabel(app.ArduinoConnectionPanel);
            app.SerialPortEditFieldLabel.Text = 'Serial Port';
            app.SerialPortEditField = uieditfield(app.ArduinoConnectionPanel, 'text');
            app.SerialPortEditField.Value = 'COM3';
            app.ConnectionLamp = uilamp(app.ArduinoConnectionPanel);
            app.TextArea = uitextarea(app.LeftPanel);
            app.TextArea.Value = {'Hello!'};
            app.LoadCellGroup = uitabgroup(app.LeftPanel);
            app.FrontLeftTab = uitab(app.LoadCellGroup);
            app.FrontLeftTab.Title = 'Front Left';
            app.DataOutPinEditFieldLabel = uilabel(app.FrontLeftTab);
            app.DataOutPinEditFieldLabel.Text = 'Data Out Pin';
            app.FLDataOutPinEditField = uieditfield(app.FrontLeftTab, 'text');
            app.FLDataOutPinEditField.Value = 'D4';
            app.ClockSCKPinEditFieldLabel = uilabel(app.FrontLeftTab);
            app.ClockSCKPinEditFieldLabel.Text = 'Clock (SCK) Pin';
            app.FLClockSCKPinEditField = uieditfield(app.FrontLeftTab, 'text');
            app.FLClockSCKPinEditField.Value = 'D5';
            app.TareValueEditFieldLabel = uilabel(app.FrontLeftTab);
            app.TareValueEditFieldLabel.Text = 'Tare Value';
            app.FLTareValueEditField = uieditfield(app.FrontLeftTab, 'numeric');
            app.FLTareValueEditField.ValueDisplayFormat = '%.0f';
            app.FLTareValueEditField.Value = 8393436.3;
            app.ScaleFactorEditFieldLabel = uilabel(app.FrontLeftTab);
            app.ScaleFactorEditFieldLabel.Text = 'Scale Factor';
            app.FLScaleFactorEditField = uieditfield(app.FrontLeftTab, 'numeric');
            app.FLScaleFactorEditField.ValueDisplayFormat = '%.2f';
            app.FLScaleFactorEditField.Value = 45.1545774647835;
            app.MaxLoadgEditFieldLabel = uilabel(app.FrontLeftTab);
            app.MaxLoadgEditFieldLabel.Text = 'Max Load (g)';
            app.FLMaxLoadgEditField = uieditfield(app.FrontLeftTab, 'numeric');
            app.FLMaxLoadgEditField.ValueDisplayFormat = '%.0f';
            app.FLMaxLoadgEditField.Value = 100000;
            app.FLTareButton = uibutton(app.FrontLeftTab, 'push');
            app.FLTareButton.ButtonPushedFcn = createCallbackFcn(app, @FLTareButtonPushed, true);
            app.FLTareButton.Text = {'Tare'; ''};
            app.FLCalibrateButton = uibutton(app.FrontLeftTab, 'push');
            app.FLCalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @FLCalibrateButtonPushed, true);
            app.FLCalibrateButton.Text = {'Calibrate'; ''};
            app.KnownWeightgLabel = uilabel(app.FrontLeftTab);
            app.KnownWeightgLabel.Text = 'Known Weight (g)';
            app.FLKnownWeightgEditField = uieditfield(app.FrontLeftTab, 'numeric');
            app.FLKnownWeightgEditField.ValueDisplayFormat = '%.2f';
            app.FLKnownWeightgEditField.Value = 284;
            app.FLDataLamp = uilamp(app.FrontLeftTab);
            app.FLReceiveDataButton = uibutton(app.FrontLeftTab, 'push');
            app.FLReceiveDataButton.ButtonPushedFcn = createCallbackFcn(app, @FLReceiveDataButtonPushed, true);
            app.FLReceiveDataButton.Text = 'Receive Data';
            app.FLStopDataButton = uibutton(app.FrontLeftTab, 'push');
            app.FLStopDataButton.ButtonPushedFcn = createCallbackFcn(app, @FLStopDataButtonPushed, true);
            app.FLStopDataButton.Text = {'Stop Data'; ''};
            app.PauseReadingsButton = uibutton(app.FrontLeftTab, 'push');
            app.PauseReadingsButton.ButtonPushedFcn = createCallbackFcn(app, @PauseReadingsButtonPushed, true);
            app.PauseReadingsButton.Text = 'Pause Readings';
            app.ResumeReadingsButton = uibutton(app.FrontLeftTab, 'push');
            app.ResumeReadingsButton.ButtonPushedFcn = createCallbackFcn(app, @ResumeReadingsButtonPushed, true);
            app.ResumeReadingsButton.Text = 'Resume Readings';
            app.FrontRightTab = uitab(app.LoadCellGroup);
            app.FrontRightTab.Title = 'Front Right';
            app.BackLeftTab = uitab(app.LoadCellGroup);
            app.BackLeftTab.Title = 'Back Left';
            app.BackRightTab = uitab(app.LoadCellGroup);
            app.BackRightTab.Title = 'Back Right';
            app.CenterPanel = uipanel(app.GridLayout);
            app.FLLoadCell = uieditfield(app.CenterPanel, 'numeric');
            app.FLLoadCell.ValueDisplayFormat = '%.2f';
            app.RetrieveRawValueFLButton = uibutton(app.CenterPanel, 'push');
            app.RetrieveRawValueFLButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveRawValueFLButtonPushed, true);
            app.RetrieveRawValueFLButton.Text = {'Retrieve Raw Value FL'; ''};
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, {'Live Data Acquisition'; ''})
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Load (g)')
            app.FLLabel = uilabel(app.CenterPanel);
            app.FLLabel.Text = 'FL';
            app.LoadCellLabel = uilabel(app.CenterPanel);
            app.LoadCellLabel.Text = 'Load Cell:';
            app.UITable = uitable(app.CenterPanel);
            app.UITable.ColumnName = {'Time'; 'Deformation Angle'; 'Torque'; 'Torsional Rigidity'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.FRLoadCell = uieditfield(app.CenterPanel, 'numeric');
            app.FRLoadCell.ValueDisplayFormat = '%.2f';
            app.FRLabel = uilabel(app.CenterPanel);
            app.FRLabel.Text = 'FR';
            app.RightPanel = uipanel(app.GridLayout);
            app.TorsionalRigidityCalculationPanel = uipanel(app.RightPanel);
            app.TorsionalRigidityCalculationPanel.Title = 'Torsional Rigidity Calculation';
            app.InitialPositionEditFieldLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.InitialPositionEditFieldLabel.Text = 'Initial Position';
            app.InitialPosition1EditField = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.DialIndicatorHubInLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.DialIndicatorHubInLabel.Text = 'Dial Indicator (Hub) [In.]';
            app.DisplacedPositionEditField_2Label = uilabel(app.TorsionalRigidityCalculationPanel);
            app.DisplacedPositionEditField_2Label.Text = 'Displaced Position';
            app.DisplacedPosition1EditField = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.DisplacedPosition1EditField.Value = 0.1;
            app.DialIndicatorNodeinLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.DialIndicatorNodeinLabel.Text = 'Dial Indicator (Node) [in.]';
            app.InitialPositionEditField_2Label = uilabel(app.TorsionalRigidityCalculationPanel);
            app.InitialPositionEditField_2Label.Text = 'Initial Position';
            app.InitialPosition2EditField = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.DisplacedPositionEditFieldLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.DisplacedPositionEditFieldLabel.Text = 'Displaced Position';
            app.DisplacedPosition2EditField = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.DisplacedPosition2EditField.Value = 0.3;
            app.GetTorsionalRigidityButton = uibutton(app.TorsionalRigidityCalculationPanel, 'push');
            app.GetTorsionalRigidityButton.ButtonPushedFcn = createCallbackFcn(app, @GetTorsionalRigidityButtonPushed, true);
            app.GetTorsionalRigidityButton.Text = 'Get Torsional Rigidity';
            app.TorsionalRigidityOutput = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.TorsionalRigidityOutput.ValueDisplayFormat = '%.2f';
            app.DeformationAngleLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.DeformationAngleLabel.Text = {'Deformation Angle (°)'; ''};
            app.DeformationAngle1EditField = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.DeformationAngle1EditField.ValueDisplayFormat = '%.2f';
            app.Label = uilabel(app.TorsionalRigidityCalculationPanel);
            app.Label.Text = '_________________________________';
            app.DistanceBetweenDialIndicatorsinEditFieldLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.DistanceBetweenDialIndicatorsinEditFieldLabel.Text = 'Distance Between Dial Indicators [in.]';
            app.DistanceBetweenDialIndicatorsinEditField = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.DistanceBetweenDialIndicatorsinEditField.Value = 12;
            app.DistancebetweentheJackScrewsinEditFieldLabel = uilabel(app.TorsionalRigidityCalculationPanel);
            app.DistancebetweentheJackScrewsinEditFieldLabel.Text = 'Distance between the Jack Screws [in.]';
            app.DistancebetweentheJackScrewsinEditField = uieditfield(app.TorsionalRigidityCalculationPanel, 'numeric');
            app.DistancebetweentheJackScrewsinEditField.Value = 40;
            app.ResetDisplacedValuesButton = uibutton(app.TorsionalRigidityCalculationPanel, 'push');
            app.ResetDisplacedValuesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDisplacedValuesButtonPushed, true);
            app.ResetDisplacedValuesButton.Text = 'Reset Displaced Values';
            app.SaveLamp = uilamp(app.RightPanel);
            app.SaveAllValuestoExcelSheetsLabel = uilabel(app.RightPanel);
            app.SaveAllValuestoExcelSheetsLabel.Text = 'Save All Values to Excel Sheets?';
            app.SaveTorsionalRigidity = uiswitch(app.RightPanel, 'slider');
            app.SaveTorsionalRigidity.Items = {'No', 'Yes'};
            app.SaveTorsionalRigidity.ValueChangedFcn = createCallbackFcn(app, @SaveTorsionalRigidityValueChanged, true);
            app.SaveTorsionalRigidity.Value = 'No';
            app.LoadCellValuesandTorsionalRigidityValueswithTimeLabel = uilabel(app.RightPanel);
            app.LoadCellValuesandTorsionalRigidityValueswithTimeLabel.Text = '(Load Cell Values and Torsional Rigidity Values with Time)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = uhfsae_data_acquisition_system

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end